classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReaddataPanel                   matlab.ui.container.Panel
        ReadButton                      matlab.ui.control.Button
        InputimageTextAreaLabel         matlab.ui.control.Label
        InputimageTextArea              matlab.ui.control.TextArea
        OutputdirTextAreaLabel          matlab.ui.control.Label
        OutputdirTextArea               matlab.ui.control.TextArea
        Lamda_exnmTextAreaLabel         matlab.ui.control.Label
        Lamda_exnmTextArea              matlab.ui.control.TextArea
        Lamda_emnmTextAreaLabel         matlab.ui.control.Label
        Lamda_emnmTextArea              matlab.ui.control.TextArea
        Pixelsize_xynmTextAreaLabel     matlab.ui.control.Label
        Pixelsize_xynmTextArea          matlab.ui.control.TextArea
        Pixelsize_znmTextAreaLabel      matlab.ui.control.Label
        Pixelsize_znmTextArea           matlab.ui.control.TextArea
        ChannelTotalTextAreaLabel       matlab.ui.control.Label
        ChannelTotalTextArea            matlab.ui.control.TextArea
        FrameTotalTextAreaLabel         matlab.ui.control.Label
        FrameTotalTextArea              matlab.ui.control.TextArea
        NATextAreaLabel                 matlab.ui.control.Label
        NATextArea                      matlab.ui.control.TextArea
        ChannelTotalTextArea_2          matlab.ui.control.TextArea
        ChannelTotalTextArea_3          matlab.ui.control.TextArea
        ChannelTotalTextAreaLabel_2     matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SystemButtonGroup               matlab.ui.container.ButtonGroup
        OMXButton                       matlab.ui.control.RadioButton
        NSIMButton                      matlab.ui.control.RadioButton
        SetButton                       matlab.ui.control.Button
        HomeButton                      matlab.ui.control.RadioButton
        ChannelTotalTextAreaLabel_4     matlab.ui.control.Label
        FilterandreconstructionButtonGroup  matlab.ui.container.ButtonGroup
        lamda_1TextAreaLabel            matlab.ui.control.Label
        lamda_1TextArea                 matlab.ui.control.TextArea
        FilterButton                    matlab.ui.control.Button
        EdgetaperpixelTextAreaLabel_4   matlab.ui.control.Label
        lamda_2TextAreaLabel            matlab.ui.control.Label
        lamda_2TextArea                 matlab.ui.control.TextArea
        Notchwidth1TextAreaLabel        matlab.ui.control.Label
        Notchwidth1TextArea             matlab.ui.control.TextArea
        Notchdepth1TextAreaLabel        matlab.ui.control.Label
        Notchdepth1TextArea             matlab.ui.control.TextArea
        Notchwidth2TextAreaLabel        matlab.ui.control.Label
        Notchwidth2TextArea             matlab.ui.control.TextArea
        Notchdepth2TextAreaLabel        matlab.ui.control.Label
        Notchdepth2TextArea             matlab.ui.control.TextArea
        EdgetaperpixelTextAreaLabel_5   matlab.ui.control.Label
        EdgetaperpixelTextAreaLabel_6   matlab.ui.control.Label
        EdgetaperpixelTextAreaLabel_7   matlab.ui.control.Label
        EdgetaperpixelTextAreaLabel_8   matlab.ui.control.Label
        EdgetaperpixelTextAreaLabel_9   matlab.ui.control.Label
        ChannelTotalTextAreaLabel_3     matlab.ui.control.Label
        ProcessdataButtonGroup_3        matlab.ui.container.ButtonGroup
        EdgetaperpixelTextArea_3Label   matlab.ui.control.Label
        EdgetaperpixelTextArea_3        matlab.ui.control.TextArea
        AttenuationTextArea_3Label      matlab.ui.control.Label
        AttenuationTextArea_3           matlab.ui.control.TextArea
        ProcessButton_3                 matlab.ui.control.Button
        EdgetaperpixelTextAreaLabel_12  matlab.ui.control.Label
        EdgetaperpixelTextAreaLabel_13  matlab.ui.control.Label
        SimulatedOTFButtonGroup_2       matlab.ui.container.ButtonGroup
        RefovTextArea_2Label            matlab.ui.control.Label
        RefovTextArea_2                 matlab.ui.control.TextArea
        RedimmTextArea_2Label           matlab.ui.control.Label
        RedimmTextArea_2                matlab.ui.control.TextArea
        RefmedTextArea_2Label           matlab.ui.control.Label
        RefmedTextArea_2                matlab.ui.control.TextArea
        FwdumTextArea_2Label            matlab.ui.control.Label
        FwdumTextArea_2                 matlab.ui.control.TextArea
        DepthumTextArea_2Label          matlab.ui.control.Label
        DepthumTextArea_2               matlab.ui.control.TextArea
        ExperimentalOTFButtonGroup_2    matlab.ui.container.ButtonGroup
        DirTextArea_2Label              matlab.ui.control.Label
        DirTextArea_2                   matlab.ui.control.TextArea
        Button_3                        matlab.ui.control.Button
        OTFButtonGroup                  matlab.ui.container.ButtonGroup
        SimulatedButton                 matlab.ui.control.RadioButton
        ExperimentalButton              matlab.ui.control.RadioButton
        ProcessdataButtonGroup_4        matlab.ui.container.ButtonGroup
        pSIMButton                      matlab.ui.control.Button
        IfCalibrationButtonGroup        matlab.ui.container.ButtonGroup
        NoButton                        matlab.ui.control.RadioButton
        YesButton                       matlab.ui.control.RadioButton
        Calib1TextAreaLabel             matlab.ui.control.Label
        Calib1TextArea                  matlab.ui.control.TextArea
        Calib2TextAreaLabel             matlab.ui.control.Label
        Calib2TextArea                  matlab.ui.control.TextArea
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        statusTextAreaLabel             matlab.ui.control.Label
        statusTextArea                  matlab.ui.control.TextArea
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        dataparams
        WF
        Nx
        Ny
        Nz
        raw_image
        freq
        ang
        pha
        module
        sum_fft
        final_image
        OTFflag
        psim_image
        output
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Open-3DSIM v1.0';
        end

        % Button pushed function: Button
        function Input_image_dir(app, event)
            [filename, filepath] = uigetfile('*.dv;*.tif;*.tiff;*.nd2','Please choose raw image (*.dv / *.tif / *.tiff)');
            if filepath~=0
                app.InputimageTextArea.Value = [filepath filename];
                app.Button_2.Enable = 'on';
                app.statusTextArea.Value = {'Please choose the output directory'};
            end
        end

        % Button pushed function: Button_2
        function Ourput_dir_dir(app, event)
            [filepath] = uigetdir;
            if filepath~=0
                app.OutputdirTextArea.Value = filepath;
                app.ReadButton.Enable = 'on';
                app.statusTextArea.Value = {'Please click the button Read'};
            end
        end

        % Button pushed function: Button_3
        function OTF_dir(app, event)
            [filename, filepath] = uigetfile('*.*','Please choose experimental OTF (*.tiff)');
            if filepath~=0
                app.DirTextArea_2.Value = [filepath filename];
            else
                app.DirTextArea_2.Value = "0";
            end
        end

        % Button pushed function: ReadButton
        function Read(app, event)
            app.ReadButton.Enable = 'off';
            app.statusTextArea.Value = {'Reading'};
            pause(1);
            addpath('.\help_functions\');
            addpath('.\lib\bfmatlab\');
            addpath('.\lib\diplib\share\DIPimage');
            setenv('PATH',['.\lib\diplib\bin',';',getenv('PATH')]);
            datasets = app.InputimageTextArea.Value{1};
            app.output = strcat(app.OutputdirTextArea.Value{1},'\');
            numchannels = app.ChannelTotalTextArea_2.Value;   numchannels = numchannels{1};   numchannels = str2num(numchannels);
            jchannel = app.ChannelTotalTextArea.Value;        jchannel = jchannel{1};         jchannel = str2num(jchannel);
            numframes = app.ChannelTotalTextArea_3.Value;     numframes = numframes{1};       numframes = str2num(numframes);
            jframe = app.FrameTotalTextArea.Value;            jframe = jframe{1};             jframe = str2num(jframe);
            
            try
                if app.NSIMButton.Value == 1 
                    [app.dataparams,app.WF,app.Nx,app.Ny,app.Nz,app.raw_image] = read_data_2(datasets,app.output,numchannels,numframes,jchannel,jframe);
                elseif app.HomeButton.Value == 1 
                    [app.dataparams,app.WF,app.Nx,app.Ny,app.Nz,app.raw_image] = read_data_3(datasets,app.output,numchannels,numframes,jchannel,jframe);
                else
                    [app.dataparams,app.WF,app.Nx,app.Ny,app.Nz,app.raw_image] = read_data_1(datasets,app.output,numchannels,numframes,jchannel,jframe);
                end         
            catch ME
                fiderror = fopen('logFile.txt','a+');
                fprintf(fiderror,'%s\n',ME.message);
                for e=1:length(ME.stack)
                    fprintf(fiderror,'%s at %i\n', ME.stack(e).name, ME.stack(e).line);
                end
                % close file
                fclose(fiderror);
            end
           
            app.Notchwidth1TextArea.Value = num2str(0.4*app.Nx/1024);
            app.Notchwidth2TextArea.Value = num2str(0.5*app.Nx/1024);
            app.ProcessButton_3.Enable = 'on';
            app.statusTextArea.Value = {'Please chose OTF and click Process'};
        end

        % Button pushed function: ProcessButton_3
        function Process(app, event)
            app.ProcessButton_3.Enable = 'off';
            app.statusTextArea.Value = {'Processing'};
            pause(1);
            rawpixelsize = [0 0 0];
            rawpixelsize(1) = str2num(app.Pixelsize_xynmTextArea.Value{1});
            rawpixelsize(2) = str2num(app.Pixelsize_xynmTextArea.Value{1});
            rawpixelsize(3) = str2num(app.Pixelsize_znmTextArea.Value{1});
            NA = str2num(app.NATextArea.Value{1});
            refmed = str2num(app.RefmedTextArea_2.Value{1});
            refcov = str2num(app.RefovTextArea_2.Value{1});
            refimm = str2num(app.RedimmTextArea_2.Value{1});
            exwavelength = str2num(app.Lamda_exnmTextArea.Value{1});
            emwavelength = str2num(app.Lamda_emnmTextArea.Value{1});
            fwd = 1000*str2num(app.FwdumTextArea_2.Value{1});
            depth = 1000*str2num(app.DepthumTextArea_2.Value{1});
            notchwidthxy1 = str2num(app.Notchwidth1TextArea.Value{1});
            notchdips1 = str2num(app.Notchdepth1TextArea.Value{1});
            notchwidthxy2 = str2num(app.Notchwidth2TextArea.Value{1});
            notchdips2 = str2num(app.Notchdepth2TextArea.Value{1});
            OTF_name = app.DirTextArea_2.Value{1};
            num_taper = str2num(app.EdgetaperpixelTextArea_3.Value{1});
            attenuation = str2num(app.AttenuationTextArea_3.Value{1});
            if app.ExperimentalButton.Value == 1 && app.DirTextArea_2.Value ~= "0"
                app.OTFflag = 0;
            else
                app.OTFflag = 1;
            end
            try
                [app.dataparams,app.freq,app.ang,app.pha,app.module,app.sum_fft] = process_data(app.dataparams,rawpixelsize,NA,refmed,refcov,refimm,exwavelength,emwavelength,fwd,depth,notchwidthxy1,notchdips1,notchwidthxy2,notchdips2,app.OTFflag,OTF_name,num_taper,attenuation);
            catch ME
                % open file
                fiderror = fopen('logFile.txt','a+');
                % write the error to file
                fprintf(fiderror,'%s\n',ME.message);
                % 输出所有ME.stack堆积的报错内容。
                % ME.stack(e).name为出现错误的文件名
                % ME.stack(e).line为出现错误的位置（第几行）
                for e=1:length(ME.stack)
                    fprintf(fiderror,'%s at %i\n', ME.stack(e).name, ME.stack(e).line);
                end
                % close file
                fclose(fiderror);
            end
            freq1 = sqrt(app.freq(1,1,1,1)^2+app.freq(2,1,1,1)^2);
            freq2 = sqrt(app.freq(1,1,1,2)^2+app.freq(2,1,1,2)^2);
            freq3 = sqrt(app.freq(1,1,1,3)^2+app.freq(2,1,1,3)^2);
            ang1 = app.ang(1,1,1,1);
            ang2 = app.ang(1,1,1,2);
            ang3 = app.ang(1,1,1,3);
            pha1 = app.pha(1,1,1,1);
            pha2 = app.pha(1,1,1,2);
            pha3 = app.pha(1,1,1,3);
            module11 = app.module(1,1);
            module12 = app.module(1,2);
            module21 = app.module(1,3);
            module22 = app.module(1,4);
            module31 = app.module(1,5);
            module32 = app.module(1,6);
            if app.module(1,1)>0.1&&app.module(1,2)>0.1
                eva1 = 'Good';
            elseif app.module(1,1)>0.04&&app.module(1,2)>0.04
                eva1 = 'Feasible';
            else
                eva1 = 'Bad';
            end
            if app.module(1,3)>0.1&&app.module(1,4)>0.1
                eva2 = 'Good';
            elseif app.module(1,3)>0.04&&app.module(1,4)>0.04
                eva2 = 'Feasible';
            else
                eva2 = 'Bad';
            end
            if app.module(1,5)>0.1&&app.module(1,6)>0.1
                eva3 = 'Good';
            elseif app.module(1,5)>0.04&&app.module(1,6)>0.04
                eva3 = 'Feasible';
            else
                eva3 = 'Bad';
            end
            app.UITable.Data{1,1} = 1;
            app.UITable.Data{2,1} = 2;
            app.UITable.Data{3,1} = 3;
            app.UITable.Data{1,2} = freq1;
            app.UITable.Data{2,2} = freq2;
            app.UITable.Data{3,2} = freq3;
            app.UITable.Data{1,3} = ang1;
            app.UITable.Data{2,3} = ang2;
            app.UITable.Data{3,3} = ang3;
            app.UITable.Data{1,4} = pha1;
            app.UITable.Data{2,4} = pha2;
            app.UITable.Data{3,4} = pha3;
            app.UITable.Data{1,5} = module11;
            app.UITable.Data{2,5} = module21;
            app.UITable.Data{3,5} = module31;
            app.UITable.Data{1,6} = module12;
            app.UITable.Data{2,6} = module22;
            app.UITable.Data{3,6} = module32;
            app.UITable.Data{1,7} = eva1;
            app.UITable.Data{2,7} = eva2;
            app.UITable.Data{3,7} = eva3;
            
            app.statusTextArea.Value = {'Please click Filter'};
            app.FilterButton.Enable = 'on';
        end

        % Button pushed function: FilterButton
        function Filter(app, event)
            app.statusTextArea.Value = {'Filtering'};
            pause(1);
            lambdaregul1 = str2num(app.lamda_1TextArea.Value{1});
            lambdaregul2 = str2num(app.lamda_2TextArea.Value{1});
            [app.final_image] = filter_3D(app.dataparams,app.sum_fft,app.Nx,app.Ny,app.Nz,lambdaregul1,lambdaregul2,app.output);
            app.pSIMButton.Enable = 'on';
            app.Button_6.Enable = 'on';
            app.Button_5.Enable = 'on';
            app.statusTextArea.Value = {'Please click pSIM'};
        end

        % Callback function
        function table(app, event)
            
        end

        % Button pushed function: pSIMButton
        function pSIM(app, event)
            app.statusTextArea.Value = {'pSIMing'};
            pause(1);
            calib1_file = app.Calib1TextArea.Value{1};
            calib2_file = app.Calib2TextArea.Value{1};
            if app.YesButton.Value == 1 && calib1_file~="0"  && calib2_file~="0"
                calib_type = 1;
            else
                calib_type = 0;
            end
            [app.psim_image] = pSIM(app.raw_image,app.final_image,app.ang,calib1_file,calib2_file,app.Nz,app.Nx,app.Ny,app.output,calib_type);
            app.statusTextArea.Value = {'Finished'};
        end

        % Button pushed function: Button_6
        function Calib1(app, event)
            [filename, filepath] = uigetfile('*.*','Please choose Calib1 (*.tif)');
            if filepath~=0
                app.Calib1TextArea.Value = [filepath filename];
            else
                app.Calib1TextArea.Value = "0";
            end
        end

        % Button pushed function: Button_5
        function Calib2(app, event)
            [filename, filepath] = uigetfile('*.*','Please choose Calib2 (*.tif)');
            if filepath~=0
                app.Calib2TextArea.Value = [filepath filename];
            else
                app.Calib2TextArea.Value = "0";
            end
        end

        % Button pushed function: SetButton
        function Setsystem(app, event)
            app.Button.Enable = 'on';
            app.statusTextArea.Value = {'Please choose the input image'};
            if app.NSIMButton.Value == 1 
            app.RefmedTextArea_2.Value{1} = num2str(1.520);
            app.RefovTextArea_2.Value{1} = num2str(1.512);
            app.RedimmTextArea_2.Value{1} = num2str(1.512);
            app.FwdumTextArea_2.Value{1} = num2str(120);    
            app.NATextArea.Value{1} = num2str(1.49);
            app.Pixelsize_xynmTextArea.Value{1} = num2str(65);
            app.Pixelsize_znmTextArea.Value{1} = num2str(120); 
            app.Lamda_exnmTextArea.Value = num2str(488); 
            app.Lamda_emnmTextArea.Value = num2str(525); 
            end
            if app.OMXButton.Value == 1
            app.RefmedTextArea_2.Value{1} = num2str(1.47);
            app.RefovTextArea_2.Value{1} = num2str(1.512);
            app.RedimmTextArea_2.Value{1} = num2str(1.518);
            app.FwdumTextArea_2.Value{1} = num2str(140);    
            app.NATextArea.Value{1} = num2str(1.4);
            app.Pixelsize_xynmTextArea.Value{1} = num2str(80);
            app.Pixelsize_znmTextArea.Value{1} = num2str(125); 
            app.Lamda_exnmTextArea.Value = num2str(488); 
            app.Lamda_emnmTextArea.Value = num2str(528); 
            end
            if app.HomeButton.Value == 1
            app.RefmedTextArea_2.Value{1} = num2str(1.47);
            app.RefovTextArea_2.Value{1} = num2str(1.512);
            app.RedimmTextArea_2.Value{1} = num2str(1.518);
            app.FwdumTextArea_2.Value{1} = num2str(120);    
            app.NATextArea.Value{1} = num2str(1.49);
            app.Pixelsize_xynmTextArea.Value{1} = num2str(65);
            app.Pixelsize_znmTextArea.Value{1} = num2str(125);  
            app.Lamda_exnmTextArea.Value = num2str(561); 
            app.Lamda_emnmTextArea.Value = num2str(610); 
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.ReaddataPanel = uipanel(app.UIFigure);
            app.ReaddataPanel.Title = '                                                    1. Read data';
            app.ReadButton = uibutton(app.ReaddataPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @Read, true);
            app.ReadButton.Enable = 'off';
            app.ReadButton.Text = 'Read';
            app.InputimageTextAreaLabel = uilabel(app.ReaddataPanel);
            app.InputimageTextAreaLabel.Text = 'Input image';
            app.InputimageTextArea = uitextarea(app.ReaddataPanel);
            app.InputimageTextArea.Value = {'0'};
            app.OutputdirTextAreaLabel = uilabel(app.ReaddataPanel);
            app.OutputdirTextAreaLabel.Text = 'Output dir';
            app.OutputdirTextArea = uitextarea(app.ReaddataPanel);
            app.OutputdirTextArea.Value = {'0'};
            app.Lamda_exnmTextAreaLabel = uilabel(app.ReaddataPanel);
            app.Lamda_exnmTextAreaLabel.Text = 'Lamda_ex(nm)';
            app.Lamda_exnmTextArea = uitextarea(app.ReaddataPanel);
            app.Lamda_exnmTextArea.Value = {'488'};
            app.Lamda_emnmTextAreaLabel = uilabel(app.ReaddataPanel);
            app.Lamda_emnmTextAreaLabel.Text = 'Lamda_em(nm)';
            app.Lamda_emnmTextArea = uitextarea(app.ReaddataPanel);
            app.Lamda_emnmTextArea.Value = {'528'};
            app.Pixelsize_xynmTextAreaLabel = uilabel(app.ReaddataPanel);
            app.Pixelsize_xynmTextAreaLabel.Text = 'Pixelsize_xy(nm)';
            app.Pixelsize_xynmTextArea = uitextarea(app.ReaddataPanel);
            app.Pixelsize_xynmTextArea.Value = {'80'};
            app.Pixelsize_znmTextAreaLabel = uilabel(app.ReaddataPanel);
            app.Pixelsize_znmTextAreaLabel.Text = 'Pixelsize_z(nm)';
            app.Pixelsize_znmTextArea = uitextarea(app.ReaddataPanel);
            app.Pixelsize_znmTextArea.Value = {'125'};
            app.ChannelTotalTextAreaLabel = uilabel(app.ReaddataPanel);
            app.ChannelTotalTextAreaLabel.Text = 'Channel / Total';
            app.ChannelTotalTextArea = uitextarea(app.ReaddataPanel);
            app.ChannelTotalTextArea.Value = {'1'};
            app.FrameTotalTextAreaLabel = uilabel(app.ReaddataPanel);
            app.FrameTotalTextAreaLabel.Text = 'Frame / Total';
            app.FrameTotalTextArea = uitextarea(app.ReaddataPanel);
            app.FrameTotalTextArea.Value = {'1'};
            app.NATextAreaLabel = uilabel(app.ReaddataPanel);
            app.NATextAreaLabel.Text = 'NA';
            app.NATextArea = uitextarea(app.ReaddataPanel);
            app.NATextArea.Value = {'1.4'};
            app.ChannelTotalTextArea_2 = uitextarea(app.ReaddataPanel);
            app.ChannelTotalTextArea_2.Value = {'1'};
            app.ChannelTotalTextArea_3 = uitextarea(app.ReaddataPanel);
            app.ChannelTotalTextArea_3.Value = {'1'};
            app.ChannelTotalTextAreaLabel_2 = uilabel(app.ReaddataPanel);
            app.ChannelTotalTextAreaLabel_2.Text = '/';
            app.Button = uibutton(app.ReaddataPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Input_image_dir, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.Button_2 = uibutton(app.ReaddataPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Ourput_dir_dir, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '...';
            app.SystemButtonGroup = uibuttongroup(app.ReaddataPanel);
            app.SystemButtonGroup.Title = 'System';
            app.OMXButton = uiradiobutton(app.SystemButtonGroup);
            app.OMXButton.Text = 'OMX';
            app.OMXButton.Value = true;
            app.NSIMButton = uiradiobutton(app.SystemButtonGroup);
            app.NSIMButton.Text = 'NSIM';
            app.SetButton = uibutton(app.SystemButtonGroup, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @Setsystem, true);
            app.SetButton.Text = 'Set';
            app.HomeButton = uiradiobutton(app.SystemButtonGroup);
            app.HomeButton.Text = 'Home';
            app.ChannelTotalTextAreaLabel_4 = uilabel(app.ReaddataPanel);
            app.ChannelTotalTextAreaLabel_4.Text = '/';
            app.FilterandreconstructionButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterandreconstructionButtonGroup.Title = '                                           3. Filter and reconstruction';
            app.lamda_1TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.lamda_1TextAreaLabel.Text = 'lamda_1';
            app.lamda_1TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.lamda_1TextArea.Value = {'0.5'};
            app.FilterButton = uibutton(app.FilterandreconstructionButtonGroup, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @Filter, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Text = 'Filter';
            app.EdgetaperpixelTextAreaLabel_4 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_4.Text = '0.4-2 are suggested';
            app.lamda_2TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.lamda_2TextAreaLabel.Text = 'lamda_2';
            app.lamda_2TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.lamda_2TextArea.Value = {'0.1'};
            app.Notchwidth1TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.Notchwidth1TextAreaLabel.Text = 'Notchwidth1';
            app.Notchwidth1TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.Notchwidth1TextArea.Value = {'0.4'};
            app.Notchdepth1TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.Notchdepth1TextAreaLabel.Text = 'Notchdepth1';
            app.Notchdepth1TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.Notchdepth1TextArea.Value = {'0.92'};
            app.Notchwidth2TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.Notchwidth2TextAreaLabel.Text = 'Notchwidth2';
            app.Notchwidth2TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.Notchwidth2TextArea.Value = {'0.5'};
            app.Notchdepth2TextAreaLabel = uilabel(app.FilterandreconstructionButtonGroup);
            app.Notchdepth2TextAreaLabel.Text = 'Notchdepth2';
            app.Notchdepth2TextArea = uitextarea(app.FilterandreconstructionButtonGroup);
            app.Notchdepth2TextArea.Value = {'0.98'};
            app.EdgetaperpixelTextAreaLabel_5 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_5.Text = '0.01-0.4 are suggested';
            app.EdgetaperpixelTextAreaLabel_6 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_6.Text = '0.2-0.6';
            app.EdgetaperpixelTextAreaLabel_7 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_7.Text = '0.8-0.99';
            app.EdgetaperpixelTextAreaLabel_8 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_8.Text = '0.2-0.6';
            app.EdgetaperpixelTextAreaLabel_9 = uilabel(app.FilterandreconstructionButtonGroup);
            app.EdgetaperpixelTextAreaLabel_9.Text = '0.8-0.99';
            app.ChannelTotalTextAreaLabel_3 = uilabel(app.UIFigure);
            app.ChannelTotalTextAreaLabel_3.Text = '/';
            app.ProcessdataButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.ProcessdataButtonGroup_3.Title = '                                                  2. Process data';
            app.EdgetaperpixelTextArea_3Label = uilabel(app.ProcessdataButtonGroup_3);
            app.EdgetaperpixelTextArea_3Label.Text = 'Edgetaper(pixel)';
            app.EdgetaperpixelTextArea_3 = uitextarea(app.ProcessdataButtonGroup_3);
            app.EdgetaperpixelTextArea_3.Value = {'0'};
            app.AttenuationTextArea_3Label = uilabel(app.ProcessdataButtonGroup_3);
            app.AttenuationTextArea_3Label.Text = 'Attenuation';
            app.AttenuationTextArea_3 = uitextarea(app.ProcessdataButtonGroup_3);
            app.AttenuationTextArea_3.Value = {'0'};
            app.ProcessButton_3 = uibutton(app.ProcessdataButtonGroup_3, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @Process, true);
            app.ProcessButton_3.Enable = 'off';
            app.ProcessButton_3.Text = 'Process';
            app.EdgetaperpixelTextAreaLabel_12 = uilabel(app.ProcessdataButtonGroup_3);
            app.EdgetaperpixelTextAreaLabel_12.Text = '0~10 pixel are suggested';
            app.EdgetaperpixelTextAreaLabel_13 = uilabel(app.ProcessdataButtonGroup_3);
            app.EdgetaperpixelTextAreaLabel_13.Text = '0~1 are suggested';
            app.SimulatedOTFButtonGroup_2 = uibuttongroup(app.ProcessdataButtonGroup_3);
            app.SimulatedOTFButtonGroup_2.Title = '                           Simulated OTF';
            app.RefovTextArea_2Label = uilabel(app.SimulatedOTFButtonGroup_2);
            app.RefovTextArea_2Label.Text = 'Refov';
            app.RefovTextArea_2 = uitextarea(app.SimulatedOTFButtonGroup_2);
            app.RefovTextArea_2.Value = {'1.512'};
            app.RedimmTextArea_2Label = uilabel(app.SimulatedOTFButtonGroup_2);
            app.RedimmTextArea_2Label.Text = 'Redimm';
            app.RedimmTextArea_2 = uitextarea(app.SimulatedOTFButtonGroup_2);
            app.RedimmTextArea_2.Value = {'1.518'};
            app.RefmedTextArea_2Label = uilabel(app.SimulatedOTFButtonGroup_2);
            app.RefmedTextArea_2Label.Text = 'Refmed';
            app.RefmedTextArea_2 = uitextarea(app.SimulatedOTFButtonGroup_2);
            app.RefmedTextArea_2.Value = {'1.47'};
            app.FwdumTextArea_2Label = uilabel(app.SimulatedOTFButtonGroup_2);
            app.FwdumTextArea_2Label.Text = 'Fwd(um)';
            app.FwdumTextArea_2 = uitextarea(app.SimulatedOTFButtonGroup_2);
            app.FwdumTextArea_2.Value = {'140'};
            app.DepthumTextArea_2Label = uilabel(app.SimulatedOTFButtonGroup_2);
            app.DepthumTextArea_2Label.Text = 'Depth(um)';
            app.DepthumTextArea_2 = uitextarea(app.SimulatedOTFButtonGroup_2);
            app.DepthumTextArea_2.Value = {'0'};
            app.ExperimentalOTFButtonGroup_2 = uibuttongroup(app.ProcessdataButtonGroup_3);
            app.ExperimentalOTFButtonGroup_2.Title = '     Experimental OTF';
            app.DirTextArea_2Label = uilabel(app.ExperimentalOTFButtonGroup_2);
            app.DirTextArea_2Label.Text = 'Dir';
            app.DirTextArea_2 = uitextarea(app.ExperimentalOTFButtonGroup_2);
            app.DirTextArea_2.Value = {'0'};
            app.Button_3 = uibutton(app.ExperimentalOTFButtonGroup_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @OTF_dir, true);
            app.Button_3.Text = '...';
            app.OTFButtonGroup = uibuttongroup(app.ProcessdataButtonGroup_3);
            app.OTFButtonGroup.Title = '                 OTF';
            app.SimulatedButton = uiradiobutton(app.OTFButtonGroup);
            app.SimulatedButton.Text = 'Simulated';
            app.SimulatedButton.Value = true;
            app.ExperimentalButton = uiradiobutton(app.OTFButtonGroup);
            app.ExperimentalButton.Text = 'Experimental';
            app.ProcessdataButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.ProcessdataButtonGroup_4.Title = '                                                4. Process data';
            app.pSIMButton = uibutton(app.ProcessdataButtonGroup_4, 'push');
            app.pSIMButton.ButtonPushedFcn = createCallbackFcn(app, @pSIM, true);
            app.pSIMButton.Enable = 'off';
            app.pSIMButton.Text = 'pSIM';
            app.IfCalibrationButtonGroup = uibuttongroup(app.ProcessdataButtonGroup_4);
            app.IfCalibrationButtonGroup.Title = '     If Calibration';
            app.NoButton = uiradiobutton(app.IfCalibrationButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.IfCalibrationButtonGroup);
            app.YesButton.Text = 'Yes';
            app.Calib1TextAreaLabel = uilabel(app.ProcessdataButtonGroup_4);
            app.Calib1TextAreaLabel.Text = 'Calib1';
            app.Calib1TextArea = uitextarea(app.ProcessdataButtonGroup_4);
            app.Calib2TextAreaLabel = uilabel(app.ProcessdataButtonGroup_4);
            app.Calib2TextAreaLabel.Text = 'Calib2';
            app.Calib2TextArea = uitextarea(app.ProcessdataButtonGroup_4);
            app.Button_5 = uibutton(app.ProcessdataButtonGroup_4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Calib2, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '...';
            app.Button_6 = uibutton(app.ProcessdataButtonGroup_4, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Calib1, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '...';
            app.statusTextAreaLabel = uilabel(app.UIFigure);
            app.statusTextAreaLabel.Text = 'status';
            app.statusTextArea = uitextarea(app.UIFigure);
            app.statusTextArea.Value = {'Please choose the sytem and click Set'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Direction'; 'Freq'; 'Ang'; 'Phase'; 'Mudule1'; 'Module2'; 'Eva'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end